classdef class_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            detector = load('detector_0_3.mat');
            detector = detector.detector;
            files = dir('D:\STUDIA\Magisterka\I ROK\I SEMESTR\ICZ\seria1\resized');
            for i=1:length(files)
                if ~(strcmp(files(i).name, '.') || strcmp(files(i).name, '..'))
                    filename = join([files(i).folder, '\', files(i).name]);
                    img = imread(filename);
                    [bbox, score, label] = detect(detector, img);
                    detected_img = insertShape(img, 'Rectangle', bbox);
                    imshow(detected_img, 'Parent', app.UIAxes);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Detection results')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = class_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end